classdef denoise < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ImageLabelerUIFigure   matlab.ui.Figure
        TabGroup               matlab.ui.container.TabGroup
        LabelTab               matlab.ui.container.Tab
        ExportasCSVButton      matlab.ui.control.Button
        ProgressGaugeLabel     matlab.ui.control.Label
        ProgressGauge          matlab.ui.control.SemicircularGauge
        Label_3                matlab.ui.control.Label
        SelectFolderButton     matlab.ui.control.Button
        Label_4                matlab.ui.control.Label
        Label_5                matlab.ui.control.Label
        folderLamp             matlab.ui.control.Lamp
        Lamp_3Label            matlab.ui.control.Label
        textLamp               matlab.ui.control.Lamp
        ImportTextFileButton   matlab.ui.control.Button
        Label_6                matlab.ui.control.Label
        exportLamp             matlab.ui.control.Lamp
        UIAxes                 matlab.ui.control.UIAxes
        UIAxes_2               matlab.ui.control.UIAxes
        UIAxes_3               matlab.ui.control.UIAxes
        Rankimage1ButtonGroup  matlab.ui.container.ButtonGroup
        Button                 matlab.ui.control.RadioButton
        Button_4               matlab.ui.control.RadioButton
        Button_5               matlab.ui.control.RadioButton
        Rankimage2ButtonGroup  matlab.ui.container.ButtonGroup
        Button_6               matlab.ui.control.RadioButton
        Button_7               matlab.ui.control.RadioButton
        Button_8               matlab.ui.control.RadioButton
        Rankimage3ButtonGroup  matlab.ui.container.ButtonGroup
        Button_9               matlab.ui.control.RadioButton
        Button_10              matlab.ui.control.RadioButton
        Button_11              matlab.ui.control.RadioButton
        PreviousButton         matlab.ui.control.Button
        NextButton             matlab.ui.control.Button
        DataTab                matlab.ui.container.Tab
        UITable                matlab.ui.control.Table
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        end

        % Button pushed function: ExportasCSVButton
        function ExportPush(app, event)
            app.count       = 1;
            app.maxframes   = 100;
            app.maxsave     = 50;
            app.directory   = '~/Documents/LungTransplantLabels';
            
            
            if ~exist(app.directory)
                mkdir(app.directory)
            end
        end

        % Button pushed function: ImportTextFileButton
        function ImportTextFile(app, event)
            if app.count<length(app.framelist) 
                
                timestamp = toc;
             
                new_row                     = {app.videopath,app.framenb,'True',timestamp};
                app.UITable.Data            = [app.UITable.Data;new_row];
                app.framelist{app.count,3}  = 'True';
                app.framelist{app.count,4}  = timestamp;
                
                app.framelist               = update(app,app.framelist,app.folder,app.directory,app.maxsave,app.maxframes,app.count);
                    
                
                app.count                   = app.count + 1;
            
                app.videopath               = app.framelist{app.count,1};
                app.framenb                 = app.framelist{app.count,2};
                app.frame                   = app.framelist{app.count,5};
                
                imshow(app.frame,'Parent',app.UIAxes)
                
                tic

            else
                uialert(app.ImageLabelerUIFigure,'All images are labeled','Done','Icon','success');
            end 
           
           app.ProgressGauge.Value = (app.count / length(app.framelist))*100 ;

        end

        % Button pushed function: SelectFolderButton
        function SelectFolder(app, event)
            
            ind = strfind(app.folder, '/');
            foldername              = app.folder(ind(end)+1:end);
            filename                = strcat(app.directory,'/', foldername,'.xlsx');
            writetable(cell2table(app.UITable.Data),filename,'Sheet',1,'Range','A1');
            app.textLamp.Color = 'green';
            uialert(app.ImageLabelerUIFigure,'File exported','Success','Icon','success');
            
        end

        % Button pushed function: PreviousButton
        function previousButton(app, event)
            
            if isempty(app.folder)
                uialert(app.ImageLabelerUIFigure,'First select the folder','Error','Icon','warning');
            
            elseif isempty(app.textfile)
                
                [name,path]                         = uigetfile('*.txt');
                app.ImageLabelerUIFigure.Visible    = 'off';
                app.ImageLabelerUIFigure.Visible    = 'on';
                app.textLamp.Color                  = 'green';
                fullpath                            = fullfile(path,name);
                app.textfile                        = fopen(fullpath);
                
                if isempty(app.framelist)
                    app.framelist           = findFrames(app,app.textfile,app.folder);
                end
                
                app.framelist           = loadFrames(app,app.framelist,app.folder,app.count,app.count+app.maxframes);
                
                app.videopath           = app.framelist{app.count,1};
                app.framenb             = app.framelist{app.count,2};
                app.frame               = app.framelist{app.count,5};
               
                
                imshow(app.frame,'Parent',app.UIAxes)
                
                tic
            else 
                uialert(app.ImageLabelerUIFigure,'Text file was already selected','Error','Icon','warning');
            end
        end

        % Button pushed function: NextButton
        function nextButton(app, event)
            if isempty(app.folder)
                
                app.folder                          = uigetdir;
                app.ImageLabelerUIFigure.Visible    = 'off';
                app.ImageLabelerUIFigure.Visible    = 'on';
                app.folderLamp.Color                = 'green';
  
                
                ind                     = strfind(app.folder, '/');
                foldername              = app.folder(ind(end)+1:end);
                filename                = strcat(app.directory,'/', foldername,'.mat');
                
                if exist(filename)
                    
                    file                    = load(filename);
                    app.framelist           = struct2cell(file);
                    app.framelist           = table2cell(app.framelist{1});
                    app.count               = sum(~cellfun(@isempty,app.framelist(:,4)));
                    
                    if size(app.framelist,2)>4
                        app.framelist       = [app.framelist(:,1) app.framelist(:,2) app.framelist(:,4) app.framelist(:,5)];
                        app.UITable.Data    = [app.framelist(1:app.count,1) app.framelist(1:app.count,2) app.framelist(1:app.count,3) app.framelist(1:app.count,4)];
                    else       
                        app.UITable.Data        = [app.framelist(1:app.count,1) app.framelist(1:app.count,2) app.framelist(1:app.count,3) app.framelist(1:app.count,4)];   
                    end
                    
                    app.ProgressGauge.Value = ( app.count / length(app.framelist))*100 ;
                    
                end
                
            else
                uialert(app.ImageLabelerUIFigure,'Folder was already selected','Error','Icon','warning');
            end
        end

        % Selection changed function: Rankimage1ButtonGroup
        function rank1(app, event)
            
        end

        % Selection changed function: Rankimage2ButtonGroup
        function rank2(app, event)
            
        end

        % Selection changed function: Rankimage3ButtonGroup
        function rank3(app, event)
            selectedButton = app.Rankimage1ButtonGroup.SelectedObject;


        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ImageLabelerUIFigure = uifigure;
            app.ImageLabelerUIFigure.Name = 'Image Labeler';
            app.TabGroup = uitabgroup(app.ImageLabelerUIFigure);
            app.LabelTab = uitab(app.TabGroup);
            app.LabelTab.Title = 'Label';
            app.ExportasCSVButton = uibutton(app.LabelTab, 'push');
            app.ExportasCSVButton.ButtonPushedFcn = createCallbackFcn(app, @ExportPush, true);
            app.ExportasCSVButton.Text = 'Export as CSV';
            app.ProgressGaugeLabel = uilabel(app.LabelTab);
            app.ProgressGaugeLabel.Text = 'Progress';
            app.ProgressGauge = uigauge(app.LabelTab, 'semicircular');
            app.Label_3 = uilabel(app.LabelTab);
            app.Label_3.Text = '3.';
            app.SelectFolderButton = uibutton(app.LabelTab, 'push');
            app.SelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolder, true);
            app.SelectFolderButton.Text = 'Select Folder';
            app.Label_4 = uilabel(app.LabelTab);
            app.Label_4.Text = '1.';
            app.Label_5 = uilabel(app.LabelTab);
            app.Label_5.Text = '';
            app.folderLamp = uilamp(app.LabelTab);
            app.Lamp_3Label = uilabel(app.LabelTab);
            app.Lamp_3Label.Text = '';
            app.textLamp = uilamp(app.LabelTab);
            app.ImportTextFileButton = uibutton(app.LabelTab, 'push');
            app.ImportTextFileButton.ButtonPushedFcn = createCallbackFcn(app, @ImportTextFile, true);
            app.ImportTextFileButton.Text = 'Import Text File';
            app.Label_6 = uilabel(app.LabelTab);
            app.Label_6.Text = '2.';
            app.exportLamp = uilamp(app.LabelTab);
            app.UIAxes = uiaxes(app.LabelTab);
            title(app.UIAxes, 'Image 1')
            app.UIAxes_2 = uiaxes(app.LabelTab);
            title(app.UIAxes_2, 'Image 2')
            app.UIAxes_3 = uiaxes(app.LabelTab);
            title(app.UIAxes_3, 'Image 3')
            app.Rankimage1ButtonGroup = uibuttongroup(app.LabelTab);
            app.Rankimage1ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @rank1, true);
            app.Rankimage1ButtonGroup.Title = 'Rank image 1';
            app.Button = uiradiobutton(app.Rankimage1ButtonGroup);
            app.Button.Text = '1';
            app.Button.Value = true;
            app.Button_4 = uiradiobutton(app.Rankimage1ButtonGroup);
            app.Button_4.Text = '2';
            app.Button_5 = uiradiobutton(app.Rankimage1ButtonGroup);
            app.Button_5.Text = '3';
            app.Rankimage2ButtonGroup = uibuttongroup(app.LabelTab);
            app.Rankimage2ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @rank2, true);
            app.Rankimage2ButtonGroup.Title = 'Rank image 2';
            app.Button_6 = uiradiobutton(app.Rankimage2ButtonGroup);
            app.Button_6.Text = '1';
            app.Button_6.Value = true;
            app.Button_7 = uiradiobutton(app.Rankimage2ButtonGroup);
            app.Button_7.Text = '2';
            app.Button_8 = uiradiobutton(app.Rankimage2ButtonGroup);
            app.Button_8.Text = '3';
            app.Rankimage3ButtonGroup = uibuttongroup(app.LabelTab);
            app.Rankimage3ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @rank3, true);
            app.Rankimage3ButtonGroup.Title = 'Rank image 3';
            app.Button_9 = uiradiobutton(app.Rankimage3ButtonGroup);
            app.Button_9.Text = '1';
            app.Button_9.Value = true;
            app.Button_10 = uiradiobutton(app.Rankimage3ButtonGroup);
            app.Button_10.Text = '2';
            app.Button_11 = uiradiobutton(app.Rankimage3ButtonGroup);
            app.Button_11.Text = '3';
            app.PreviousButton = uibutton(app.LabelTab, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @previousButton, true);
            app.PreviousButton.Text = 'Previous';
            app.NextButton = uibutton(app.LabelTab, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @nextButton, true);
            app.NextButton.Text = 'Next';
            app.DataTab = uitab(app.TabGroup);
            app.DataTab.Title = 'Data';
            app.UITable = uitable(app.DataTab);
            app.UITable.ColumnName = {'Video'; 'Frame'; 'Label'; 'Time'};
            app.UITable.RowName = {};
        end
    end

    methods (Access = public)

        % Construct app
        function app = denoise

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ImageLabelerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ImageLabelerUIFigure)
        end
    end
end