classdef denoise_scoring < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        TabGroup               matlab.ui.container.TabGroup
        ScoringTab             matlab.ui.container.Tab
        Label                  matlab.ui.control.Label
        folderLamp             matlab.ui.control.Lamp
        Label_2                matlab.ui.control.Label
        exportLamp             matlab.ui.control.Lamp
        SelectfolderButton     matlab.ui.control.Button
        ExportCSVButton        matlab.ui.control.Button
        ProgressGaugeLabel     matlab.ui.control.Label
        ProgressGauge          matlab.ui.control.SemicircularGauge
        NextButton             matlab.ui.control.Button
        PreviousButton         matlab.ui.control.Button
        RankImage1ButtonGroup  matlab.ui.container.ButtonGroup
        button11               matlab.ui.control.RadioButton
        button12               matlab.ui.control.RadioButton
        button13               matlab.ui.control.RadioButton
        RankImage2ButtonGroup  matlab.ui.container.ButtonGroup
        button21               matlab.ui.control.RadioButton
        button22               matlab.ui.control.RadioButton
        button23               matlab.ui.control.RadioButton
        RankImage3ButtonGroup  matlab.ui.container.ButtonGroup
        button31               matlab.ui.control.RadioButton
        button32               matlab.ui.control.RadioButton
        button33               matlab.ui.control.RadioButton
        UIAxes1                matlab.ui.control.UIAxes
        UIAxes2                matlab.ui.control.UIAxes
        UIAxes3                matlab.ui.control.UIAxes
        DataTab                matlab.ui.container.Tab
        UITable                matlab.ui.control.Table
    end


    properties (Access = private)
        count
        maxsave
        folder
        framelist
        directory
        order 
    end

    methods (Access = private)
    
        function update(app,framelist,folder,directory)
            
            if rem(app.count,app.maxsave) == 0
                    
                ind                 = strfind(folder, '/');
                foldername          = folder(ind(end)+1:end);
                filename            = strcat(directory,'/', foldername,'.mat');
                framelist           = [framelist(:,1) framelist(:,2) framelist(:,3) framelist(:,4)];
                file                = cell2table(framelist);
                    
                save(filename,'file');                   
            end
            
        end
 
        function data = loadFrames(app,folder)
            
            framelist = {};

            dafolder        = [folder '/da'];
            nlfolder       = [folder '/nl'];
            bmfolder       = [folder '/bm'];
            
            dafiles         = dir(fullfile(dafolder, '*.png')) ;
            nlfiles        = dir(fullfile(nlfolder, '*.png'));
            bmfiles        = dir(fullfile(bmfolder, '*.png'));

            uialert(app.UIFigure,'Loading Frames','Please Wait','Icon','info');


            for j=1:length(dafiles)
                
                framelist{j,1,1} = [dafolder '/' dafiles(j).name];
                framelist{j,1,2} = [nlfolder '/' nlfiles(j).name];
                framelist{j,1,3} = [bmfolder '/' bmfiles(j).name];
            end
            
    
            data = framelist;
            
        end
  
        function getRank(app,order)
            
            selected1 = app.RankImage1ButtonGroup.SelectedObject;
            selected2 = app.RankImage2ButtonGroup.SelectedObject;
            selected3 = app.RankImage3ButtonGroup.SelectedObject;
            
            switch selected1
                case app.button11
                    app.framelist{app.count,2,order(1)} = 1;
                case app.button12
                    app.framelist{app.count,2,order(1)} = 2;
                case app.button13
                    app.framelist{app.count,2,order(1)} = 3;
            end
            
            switch selected2
                case app.button21
                    app.framelist{app.count,2,order(2)} = 1;
                case app.button22
                    app.framelist{app.count,2,order(2)} = 2;
                case app.button23
                    app.framelist{app.count,2,order(2)} = 3;
            end
            
            switch selected3
                case app.button31
                    app.framelist{app.count,2,order(3)} = 1;
                case app.button32
                    app.framelist{app.count,2,order(3)} = 2;
                case app.button33
                    app.framelist{app.count,2,order(3)} = 3;
            end         
        end
        
        
        function show(app,framelist,count,order)
            
            imshow(framelist{count,1,order(1)},'Parent',app.UIAxes1)
            imshow(framelist{count,1,order(2)},'Parent',app.UIAxes2)
            imshow(framelist{count,1,order(3)},'Parent',app.UIAxes3)
     
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.count       = 1;
            app.maxsave     = 50;
            app.directory   = '~/Documents/LungTransplantScoring';
            app.order       = randperm(3);
            
            
            if ~exist(app.directory)
                mkdir(app.directory)
            end
        end

        % Button pushed function: ExportCSVButton
        function exportButton(app, event)
            ind                     = strfind(app.folder, '/');
            foldername              = app.folder(ind(end)+1:end);
            filename                = strcat(app.directory,'/', foldername,'.xlsx');
            
            writetable(cell2table(app.UITable.Data),filename,'Sheet',1,'Range','A1');
            
            app.exportLamp.Color    = 'green';
            
            uialert(app.UIFigure,'File exported','Success','Icon','success');
        end

        % Button pushed function: PreviousButton
        function previousButton(app, event)
            app.count               = app.count - 1 ;
            app.UITable.Data        = app.UITable.Data(1:app.count,:);
            
               
            show(app,app.framelist,app.count,app.order);
            
            app.ProgressGauge.Value = (app.count / length(app.framelist))*100 ;
        end

        % Button pushed function: NextButton
        function nextButton(app, event)
            if app.count<length(app.framelist) 
                
                ind                         = strfind(app.framelist{app.count,1,1}, '/');
                imagename                   = app.framelist{app.count,1,1}(ind(end)+1:end);
                
                getRank(app,app.order);

                new_row                     = {imagename,app.framelist{app.count,2,1},app.framelist{app.count,2,2},app.framelist{app.count,2,3}};
                app.UITable.Data            = [app.UITable.Data;new_row];
                
                update(app,app.framelist,app.folder,app.directory);
                                 
                app.count                   = app.count + 1;
                app.order                   = randperm(3);

                show(app,app.framelist,app.count,app.order);


            else
                uialert(app.UIFigure,'All images are labeled','Done','Icon','success');
            end 
           
           app.ProgressGauge.Value = (app.count / length(app.framelist))*100 ;
        end

        % Button pushed function: SelectfolderButton
        function importButton(app, event)
            
            if isempty(app.folder)
                
                app.folder                          = uigetdir;
                app.UIFigure.Visible    = 'off';
                app.UIFigure.Visible    = 'on';
                app.folderLamp.Color                = 'green';
      
                ind                     = strfind(app.folder, '/');
                foldername              = app.folder(ind(end)+1:end);
                filename                = strcat(app.directory,'.mat');
                
                if exist(filename)
                    
                    file                    = load(filename);
                    app.framelist           = struct2cell(file);
                    app.framelist           = table2cell(app.framelist{1});
                    app.count               = sum(~cellfun(@isempty,app.framelist(:,4)));
                    

                    app.UITable.Data        = [app.framelist(1:app.count,1) app.framelist(1:app.count,2) app.framelist(1:app.count,3) app.framelist(1:app.count,4)];   
                    
                    app.ProgressGauge.Value = ( app.count / length(app.framelist))*100 ;
                    
                else
                    app.framelist           = loadFrames(app,app.folder);
                end
                
                
                show(app,app.framelist,app.count,app.order);


            else
                uialert(app.UIFigure,'Folder was already selected','Error','Icon','warning');
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ScoringTab = uitab(app.TabGroup);
            app.ScoringTab.Title = 'Scoring';
            app.Label = uilabel(app.ScoringTab);
            app.Label.Text = '1.';
            app.folderLamp = uilamp(app.ScoringTab);
            app.Label_2 = uilabel(app.ScoringTab);
            app.Label_2.Text = '2.';
            app.exportLamp = uilamp(app.ScoringTab);
            app.SelectfolderButton = uibutton(app.ScoringTab, 'push');
            app.SelectfolderButton.ButtonPushedFcn = createCallbackFcn(app, @importButton, true);
            app.SelectfolderButton.Text = 'Select folder';
            app.ExportCSVButton = uibutton(app.ScoringTab, 'push');
            app.ExportCSVButton.ButtonPushedFcn = createCallbackFcn(app, @exportButton, true);
            app.ExportCSVButton.Text = 'Export CSV';
            app.ProgressGaugeLabel = uilabel(app.ScoringTab);
            app.ProgressGaugeLabel.Text = 'Progress';
            app.ProgressGauge = uigauge(app.ScoringTab, 'semicircular');
            app.ProgressGauge.MajorTicks = [0 25 50 75 100];
            app.NextButton = uibutton(app.ScoringTab, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @nextButton, true);
            app.NextButton.Text = 'Next';
            app.PreviousButton = uibutton(app.ScoringTab, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @previousButton, true);
            app.PreviousButton.Text = 'Previous';
            app.RankImage1ButtonGroup = uibuttongroup(app.ScoringTab);
            app.RankImage1ButtonGroup.Title = 'Rank Image 1';
            app.button11 = uiradiobutton(app.RankImage1ButtonGroup);
            app.button11.Text = '1';
            app.button11.Value = true;
            app.button12 = uiradiobutton(app.RankImage1ButtonGroup);
            app.button12.Text = '2';
            app.button13 = uiradiobutton(app.RankImage1ButtonGroup);
            app.button13.Text = '3';
            app.RankImage2ButtonGroup = uibuttongroup(app.ScoringTab);
            app.RankImage2ButtonGroup.Title = 'Rank Image 2';
            app.button21 = uiradiobutton(app.RankImage2ButtonGroup);
            app.button21.Text = '1';
            app.button21.Value = true;
            app.button22 = uiradiobutton(app.RankImage2ButtonGroup);
            app.button22.Text = '2';
            app.button23 = uiradiobutton(app.RankImage2ButtonGroup);
            app.button23.Text = '3';
            app.RankImage3ButtonGroup = uibuttongroup(app.ScoringTab);
            app.RankImage3ButtonGroup.Title = 'Rank Image 3';
            app.button31 = uiradiobutton(app.RankImage3ButtonGroup);
            app.button31.Text = '1';
            app.button31.Value = true;
            app.button32 = uiradiobutton(app.RankImage3ButtonGroup);
            app.button32.Text = '2';
            app.button33 = uiradiobutton(app.RankImage3ButtonGroup);
            app.button33.Text = '3';
            app.UIAxes1 = uiaxes(app.ScoringTab);
            title(app.UIAxes1, 'Image')
            app.UIAxes2 = uiaxes(app.ScoringTab);
            title(app.UIAxes2, 'Image')
            app.UIAxes3 = uiaxes(app.ScoringTab);
            title(app.UIAxes3, 'Image')
            app.DataTab = uitab(app.TabGroup);
            app.DataTab.Title = 'Data';
            app.UITable = uitable(app.DataTab);
            app.UITable.ColumnName = {'Image'; 'Denoising Autoencoder'; 'Non Local Means'; 'BM3D'};
            app.UITable.RowName = {};
        end
    end

    methods (Access = public)

        % Construct app
        function app = denoise_scoring

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end