classdef Thesis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ImageLabelerUIFigure  matlab.ui.Figure
        TabGroup              matlab.ui.container.TabGroup
        LabelTab              matlab.ui.container.Tab
        TrueButton            matlab.ui.control.Button
        FalseButton           matlab.ui.control.Button
        ExportasCSVButton     matlab.ui.control.Button
        ProgressGaugeLabel    matlab.ui.control.Label
        ProgressGauge         matlab.ui.control.SemicircularGauge
        UnusableButton        matlab.ui.control.Button
        ImportVideoButton     matlab.ui.control.Button
        Label                 matlab.ui.control.Label
        Label_3               matlab.ui.control.Label
        UIAxes                matlab.ui.control.UIAxes
        DataTab               matlab.ui.container.Tab
        UITable               matlab.ui.control.Table
    end


    properties (Access = private)
        count
        video
        videopath
        videoname
        frame
        nframes
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.count = 1;
        end

        % Button pushed function: TrueButton
        function TruePush(app, event)
            if hasFrame(app.video) 
                new_row = {app.videoname,app.count,'True'};
                app.UITable.Data = [app.UITable.Data;new_row];
                app.count = app.count + 1;
            
                app.frame = readFrame(app.video);
                imshow(app.frame,'Parent',app.UIAxes)
            else
                uialert(app.ImageLabelerUIFigure,'All images are labeled','Done','Icon','success');
            end 
           
           app.ProgressGauge.Value = ( app.count / app.nframes)*100 ;

        end

        % Button pushed function: FalseButton
        function FalsePush(app, event)
            
            if hasFrame(app.video) 
                new_row = {app.videoname,app.count,'False'};
                app.UITable.Data = [app.UITable.Data;new_row];
                app.count = app.count + 1;
            
                app.frame = readFrame(app.video);
                imshow(app.frame,'Parent',app.UIAxes)
            else
                uialert(app.ImageLabelerUIFigure,'All images are labeled','Done','Icon','success');
            end
            
            app.ProgressGauge.Value = ( app.count / app.nframes)*100 ;
                
        end

        % Button pushed function: ExportasCSVButton
        function ExportPush(app, event)
            filename = strcat(app.videoname,'.xlsx');
            writetable(cell2table(app.UITable.Data),filename,'Sheet',1,'Range','A1');
        end

        % Button pushed function: UnusableButton
        function UnusablePush(app, event)
            if hasFrame(app.video) 
                new_row = {app.videoname,app.count,'Unusable'};
                app.UITable.Data = [app.UITable.Data;new_row];
                app.count = app.count + 1;
            
                app.frame = readFrame(app.video);
                imshow(app.frame,'Parent',app.UIAxes)
            else
                uialert(app.ImageLabelerUIFigure,'All images are labeled','Done','Icon','success');
            end
            app.ProgressGauge.Value = ( app.count / app.nframes)*100 ;
        end

        % Button pushed function: ImportVideoButton
        function ImportPush(app, event)
            [app.videoname,path] = uigetfile('*.mp4');
            app.videopath = fullfile(path,app.videoname);
            app.video = VideoReader(app.videopath);
            app.nframes = app.video.NumberOfFrames
            app.video = VideoReader(app.videopath);
            
            app.frame = readFrame(app.video);
            imshow(app.frame,'Parent',app.UIAxes)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ImageLabelerUIFigure = uifigure;
            app.ImageLabelerUIFigure.Name = 'Image Labeler';
            app.TabGroup = uitabgroup(app.ImageLabelerUIFigure);
            app.LabelTab = uitab(app.TabGroup);
            app.LabelTab.Title = 'Label';
            app.TrueButton = uibutton(app.LabelTab, 'push');
            app.TrueButton.ButtonPushedFcn = createCallbackFcn(app, @TruePush, true);
            app.TrueButton.Text = 'True';
            app.FalseButton = uibutton(app.LabelTab, 'push');
            app.FalseButton.ButtonPushedFcn = createCallbackFcn(app, @FalsePush, true);
            app.FalseButton.Text = 'False';
            app.ExportasCSVButton = uibutton(app.LabelTab, 'push');
            app.ExportasCSVButton.ButtonPushedFcn = createCallbackFcn(app, @ExportPush, true);
            app.ExportasCSVButton.Text = 'Export as CSV';
            app.ProgressGaugeLabel = uilabel(app.LabelTab);
            app.ProgressGaugeLabel.Text = 'Progress';
            app.ProgressGauge = uigauge(app.LabelTab, 'semicircular');
            app.UnusableButton = uibutton(app.LabelTab, 'push');
            app.UnusableButton.ButtonPushedFcn = createCallbackFcn(app, @UnusablePush, true);
            app.UnusableButton.Text = 'Unusable';
            app.ImportVideoButton = uibutton(app.LabelTab, 'push');
            app.ImportVideoButton.ButtonPushedFcn = createCallbackFcn(app, @ImportPush, true);
            app.ImportVideoButton.Text = 'Import Video';
            app.Label = uilabel(app.LabelTab);
            app.Label.Text = '1.';
            app.Label_3 = uilabel(app.LabelTab);
            app.Label_3.Text = '2.';
            app.UIAxes = uiaxes(app.LabelTab);
            title(app.UIAxes, 'Label the image')
            app.DataTab = uitab(app.TabGroup);
            app.DataTab.Title = 'Data';
            app.UITable = uitable(app.DataTab);
            app.UITable.ColumnName = {'Video'; 'Frame'; 'Label'};
            app.UITable.RowName = {};
        end
    end

    methods (Access = public)

        % Construct app
        function app = Thesis

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ImageLabelerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ImageLabelerUIFigure)
        end
    end
end