classdef Thesis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ImageLabelerUIFigure  matlab.ui.Figure
        TabGroup              matlab.ui.container.TabGroup
        LabelTab              matlab.ui.container.Tab
        TrueButton            matlab.ui.control.Button
        FalseButton           matlab.ui.control.Button
        ExportasCSVButton     matlab.ui.control.Button
        ProgressGaugeLabel    matlab.ui.control.Label
        ProgressGauge         matlab.ui.control.SemicircularGauge
        UnusableButton        matlab.ui.control.Button
        SelectTextFileButton  matlab.ui.control.Button
        Label                 matlab.ui.control.Label
        Label_3               matlab.ui.control.Label
        SelectFolderButton    matlab.ui.control.Button
        Label_4               matlab.ui.control.Label
        UIAxes                matlab.ui.control.UIAxes
        DataTab               matlab.ui.container.Tab
        UITable               matlab.ui.control.Table
    end


    properties (Access = private)
        count
        video
        videopath
        splitvideopath
        framelist
        textpath
        textfile
        frame
        framenb
        nframes
        folder
    end

    methods (Access = private)
    
        function data = findFrames(app,textfile)
            
            data  = [];
            line1 = fgetl(textfile);
    
            while ischar(line1)
                
                split       = regexp(line1,'\: ','split');
                filename    = split{1};
                prefix      = regexp(filename,'/','split');
                prefix      = regexp(prefix{2},'\_png','split');
                vidname     = [prefix{1} '.mp4'];
                ranges      = split{2};
                indexes     = regexp(ranges,'\,','split');
                
                for i=1:length(indexes)
                    split   = regexp(indexes{i},'\-','split');
                    first   = str2num(split{1});
                    last    = str2num(split{2});   
                    for j=first:last
                        data  = [data ; string(vidname),  j];
                    end
                end
                
                line1    = fgetl(textfile);
                
            end
            
            
        end
                
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.count = 1;
        end

        % Button pushed function: TrueButton
        function TruePush(app, event)
            if app.count<length(app.framelist) 
                
                timestamp = toc;
             
                new_row             = {app.videopath,app.framenb,'True',timestamp};
                app.UITable.Data    = [app.UITable.Data;new_row];
                app.count           = app.count + 1;
            
                app.videopath       = fullfile(app.folder,char(app.framelist(app.count,1)));
                app.framenb         = str2num(char(app.framelist(app.count,2)));
            
                app.video           = VideoReader(app.videopath);
            
                app.frame           = read(app.video,app.framenb);
                
                imshow(app.frame,'Parent',app.UIAxes)
                
                tic

            else
                uialert(app.ImageLabelerUIFigure,'All images are labeled','Done','Icon','success');
            end 
           
           app.ProgressGauge.Value = (app.count / length(app.framelist))*100 ;

        end

        % Button pushed function: FalseButton
        function FalsePush(app, event)
            
            if app.count<length(app.framelist)
                
                timestamp = toc;
                
                new_row             = {app.videopath,app.framenb,'False',timestamp};
                app.UITable.Data    = [app.UITable.Data;new_row];
                app.count           = app.count + 1;
                
                app.videopath       = fullfile(app.folder,char(app.framelist(app.count,1)));
                app.framenb         = str2num(char(app.framelist(app.count,2)));
            
                app.video           = VideoReader(app.videopath);
            
                app.frame           = read(app.video,app.framenb);
                
                imshow(app.frame,'Parent',app.UIAxes)
                
                tic
                
            else
                uialert(app.ImageLabelerUIFigure,'All images are labeled','Done','Icon','success');
            end
            
            app.ProgressGauge.Value = ( app.count / length(app.framelist))*100 ;
                
        end

        % Button pushed function: ExportasCSVButton
        function ExportPush(app, event)
            name = regexp(app.splitvideopath,'\.mp4','split');
            name = regexp(name{1},'/','split');
            filename = strcat(name{2},'.xlsx');
            writetable(cell2table(app.UITable.Data),filename,'Sheet',1,'Range','A1');
        end

        % Button pushed function: UnusableButton
        function UnusablePush(app, event)
            if app.count<length(app.framelist)  
                
                timestamp = toc;
                
                new_row             = {app.videopath,app.framenb,'Unusable',timestamp};
                app.UITable.Data    = [app.UITable.Data;new_row];
                app.count           = app.count + 1;
                
                app.videopath       = fullfile(app.folder,char(app.framelist(app.count,1)));
                app.framenb         = str2num(char(app.framelist(app.count,2)));
            
                app.video           = VideoReader(app.videopath);
            
                app.frame           = read(app.video,app.framenb);
                
                imshow(app.frame,'Parent',app.UIAxes)
                
                tic
                
            else
                uialert(app.ImageLabelerUIFigure,'All images are labeled','Done','Icon','success');
            end
            app.ProgressGauge.Value = ( app.count /length(app.framelist))*100 ;
        end

        % Button pushed function: SelectTextFileButton
        function ImportTextFile(app, event)
            
            [name,path]     = uigetfile('*.txt');
            fullpath        = fullfile(path,name);
            app.textfile    = fopen(fullpath);
            app.framelist   = findFrames(app,app.textfile);
            
            app.videopath   = fullfile(app.folder,char(app.framelist(app.count,1)));
            app.videopath
            app.framenb     = str2num(char(app.framelist(app.count,2)));
            
            app.video       = VideoReader(app.videopath);
            
            app.frame       = read(app.video,app.framenb);
            
            imshow(app.frame,'Parent',app.UIAxes)
            
            tic
        end

        % Button pushed function: SelectFolderButton
        function SelectFolder(app, event)
            app.folder = uigetdir;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ImageLabelerUIFigure = uifigure;
            app.ImageLabelerUIFigure.Name = 'Image Labeler';
            app.TabGroup = uitabgroup(app.ImageLabelerUIFigure);
            app.LabelTab = uitab(app.TabGroup);
            app.LabelTab.Title = 'Label';
            app.TrueButton = uibutton(app.LabelTab, 'push');
            app.TrueButton.ButtonPushedFcn = createCallbackFcn(app, @TruePush, true);
            app.TrueButton.Text = 'True';
            app.FalseButton = uibutton(app.LabelTab, 'push');
            app.FalseButton.ButtonPushedFcn = createCallbackFcn(app, @FalsePush, true);
            app.FalseButton.Text = 'False';
            app.ExportasCSVButton = uibutton(app.LabelTab, 'push');
            app.ExportasCSVButton.ButtonPushedFcn = createCallbackFcn(app, @ExportPush, true);
            app.ExportasCSVButton.Text = 'Export as CSV';
            app.ProgressGaugeLabel = uilabel(app.LabelTab);
            app.ProgressGaugeLabel.Text = 'Progress';
            app.ProgressGauge = uigauge(app.LabelTab, 'semicircular');
            app.UnusableButton = uibutton(app.LabelTab, 'push');
            app.UnusableButton.ButtonPushedFcn = createCallbackFcn(app, @UnusablePush, true);
            app.UnusableButton.Text = 'Unusable';
            app.SelectTextFileButton = uibutton(app.LabelTab, 'push');
            app.SelectTextFileButton.ButtonPushedFcn = createCallbackFcn(app, @ImportTextFile, true);
            app.SelectTextFileButton.Text = 'Select Text File';
            app.Label = uilabel(app.LabelTab);
            app.Label.Text = '2.';
            app.Label_3 = uilabel(app.LabelTab);
            app.Label_3.Text = '3.';
            app.SelectFolderButton = uibutton(app.LabelTab, 'push');
            app.SelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolder, true);
            app.SelectFolderButton.Text = 'Select Folder';
            app.Label_4 = uilabel(app.LabelTab);
            app.Label_4.Text = '1.';
            app.UIAxes = uiaxes(app.LabelTab);
            title(app.UIAxes, 'Label the image')
            app.DataTab = uitab(app.TabGroup);
            app.DataTab.Title = 'Data';
            app.UITable = uitable(app.DataTab);
            app.UITable.ColumnName = {'Video'; 'Frame'; 'Label'; 'Time'};
            app.UITable.RowName = {};
        end
    end

    methods (Access = public)

        % Construct app
        function app = Thesis

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ImageLabelerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ImageLabelerUIFigure)
        end
    end
end