classdef imageLabeler < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ImageLabelerUIFigure  matlab.ui.Figure
        TabGroup              matlab.ui.container.TabGroup
        LabelTab              matlab.ui.container.Tab
        TrueButton            matlab.ui.control.Button
        FalseButton           matlab.ui.control.Button
        ExportasCSVButton     matlab.ui.control.Button
        ProgressGaugeLabel    matlab.ui.control.Label
        ProgressGauge         matlab.ui.control.SemicircularGauge
        UnusableButton        matlab.ui.control.Button
        SelectTextFileButton  matlab.ui.control.Button
        Label                 matlab.ui.control.Label
        Label_3               matlab.ui.control.Label
        SelectFolderButton    matlab.ui.control.Button
        Label_4               matlab.ui.control.Label
        Label_5               matlab.ui.control.Label
        folderLamp            matlab.ui.control.Lamp
        Lamp_2Label           matlab.ui.control.Label
        textLamp              matlab.ui.control.Lamp
        Lamp_3Label           matlab.ui.control.Label
        exportLamp            matlab.ui.control.Lamp
        BackButton            matlab.ui.control.Button
        UIAxes                matlab.ui.control.UIAxes
        DataTab               matlab.ui.container.Tab
        UITable               matlab.ui.control.Table
    end


    properties (Access = private)
        count
        video
        videopath
        framelist
        textfile
        frame
        framenb
        folder
        maxframes
        maxsave
        directory
    end

    methods (Access = private)
    
        function data = findFrames(app,textfile,folder)
            
            data        = {};
            frames      = {};
            framenbs    = {};
            names       = {};
            
            line1 = fgetl(textfile);
            
            uialert(app.ImageLabelerUIFigure,'Loading Frames','Please Wait','Icon','info');
                    
            while ischar(line1)
                
                
                split       = regexp(line1,'\: ','split');
                filename    = split{1};
                ranges      = split{2};
                
                split       = regexp(filename,'/','split');
                foldersplit = split{1};
                namesplit   = split{2};
                
                
                split       = regexp(namesplit,'\_png','split');
                vidname     = [split{1} '.mp4'];
                fullpath    = [folder '/' vidname];
                
                indexes     = regexp(ranges,'\,','split');
                
                if contains(folder,foldersplit)
                    
                    chance  = rand;
                    
                    if chance <0.05
                        first   = 1;
                        last    = 200;
                        for j=first:last
                            framenbs    = [framenbs ; j];
                            names       = [names ; vidname];
                        end
                        
                    else
                
                        for i=1:length(indexes)
                            split   = regexp(indexes{i},'\-','split');
                            chance  = rand;
                            first   = str2num(split{1});
                            last    = str2num(split{2});

                            for j=first:last
                                framenbs    = [framenbs ; j];
                                names       = [names ; vidname];
                            end   
                        end 
                    end
                    
                    data = [names  framenbs frames];
                    
                    
                end
                line1    = fgetl(textfile);
            end
            
        end
                
        
        function data = loadFrames(app,framelist,folder,start,stop)
            
            vidname         = framelist{start,1};
            fullpath        = [folder '/' vidname];
            video           = VideoReader(fullpath);
            stop            = min(stop,length(framelist));

            
            uialert(app.ImageLabelerUIFigure,'Loading Frames','Please Wait','Icon','info');
        
            for j=start:stop
                
                if strcmp(framelist{j,1},vidname)
                    framenb         = framelist{j,2};
                    frame           = read(video,framenb);
                    framelist{j,3}  = frame;
                else
                    vidname         = framelist{j,1};
                    fullpath        = [folder '/' vidname];
                    video           = VideoReader(fullpath);
                    framenb         = framelist{j,2};
                    frame           = read(video,framenb);
                    framelist{j,3}  = frame;
                end
            end
    
            data = framelist;
            
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.count       = 1;
            app.maxframes   = 50;
            app.maxsave     = 5;
            app.directory   = '~/Documents/LungTransplantLabels';
            
            
            if ~exist(app.directory)
                mkdir(app.directory)
            end
        end

        % Button pushed function: TrueButton
        function TruePush(app, event)
            if app.count<length(app.framelist) 
                
                timestamp = toc;
             
                new_row                     = {app.videopath,app.framenb,'True',timestamp};
                app.UITable.Data            = [app.UITable.Data;new_row];
                app.framelist{app.count,4}  = 'True';
                app.framelist{app.count,5}  = timestamp;
                
                if rem(app.count,app.maxframes) == 0
                    
                    app.framelist           = loadFrames(app,app.framelist,app.folder,app.count,app.count+app.maxframes);
                    
                end
                
                if rem(app.count,app.maxsave) == 0
                    
                    ind                     = strfind(app.folder, '/');
                    foldername              = app.folder(ind(end)+1:end);
                    filename                = strcat(app.directory,'/', foldername,'.mat');
                    file                    = cell2table(app.framelist);
                    
                    save(filename,'file');
                    
                end
                    
                
                app.count       = app.count + 1;
            
                app.videopath   = app.framelist{app.count,1};
                app.framenb     = app.framelist{app.count,2};
                app.frame       = app.framelist{app.count,3};
                
                imshow(app.frame,'Parent',app.UIAxes)
                
                tic

            else
                uialert(app.ImageLabelerUIFigure,'All images are labeled','Done','Icon','success');
            end 
           
           app.ProgressGauge.Value = (app.count / length(app.framelist))*100 ;

        end

        % Button pushed function: FalseButton
        function FalsePush(app, event)
            
            if app.count < length(app.framelist)
                
                timestamp = toc;
                
                new_row                     = {app.videopath,app.framenb,'False',timestamp};
                app.UITable.Data            = [app.UITable.Data;new_row];
                app.framelist{app.count,4}  = 'False';
                app.framelist{app.count,5}  = timestamp;
                
                if rem(app.count,app.maxframes) == 0
                    
                    app.framelist           = loadFrames(app,app.framelist,app.folder,app.count,app.count+app.maxframes);
                end
                
                if rem(app.count,app.maxsave) == 0
                    
                    ind                     = strfind(app.folder, '/');
                    foldername              = app.folder(ind(end)+1:end);
                    filename                = strcat(app.directory,'/', foldername,'.mat'); 
                    file                    = cell2table(app.framelist);
                    
                    save(filename,'file');
                end
                
                app.count           = app.count + 1;
                
                app.videopath   = app.framelist{app.count,1};
                app.framenb     = app.framelist{app.count,2};
                app.frame       = app.framelist{app.count,3};
                
                imshow(app.frame,'Parent',app.UIAxes)
                
                tic
                
            else
                uialert(app.ImageLabelerUIFigure,'All images are labeled','Done','Icon','success');
            end
            
            app.ProgressGauge.Value = ( app.count / length(app.framelist))*100 ;
                
        end

        % Button pushed function: ExportasCSVButton
        function ExportPush(app, event)
            
            ind = strfind(app.folder, '/');
            foldername              = app.folder(ind(end)+1:end);
            filename                = strcat(app.directory,'/', foldername,'.xlsx');
            writetable(cell2table(app.UITable.Data),filename,'Sheet',1,'Range','A1');
            app.exportLamp.Color = 'green';
            uialert(app.ImageLabelerUIFigure,'File exported','Success','Icon','success');
            
        end

        % Button pushed function: UnusableButton
        function UnusablePush(app, event)
            if app.count<length(app.framelist)  
                
                timestamp = toc;
                
                new_row                     = {app.videopath,app.framenb,'Unusable',timestamp};
                app.UITable.Data            = [app.UITable.Data;new_row];
                app.framelist{app.count,4}  = 'Unusable';
                app.framelist{app.count,5}  = timestamp;
                
                if rem(app.count,app.maxframes) == 0
                    
                    app.framelist           = loadFrames(app,app.framelist,app.folder,app.count,app.count+app.maxframes);
                    
                end
                
                if rem(app.count,app.maxsave) == 0
                    ind                     = strfind(app.folder, '/');
                    foldername              = app.folder(ind(end)+1:end);
                    filename                = strcat(app.directory,'/', foldername,'.mat'); 
                    file                    = cell2table(app.framelist);
                   
                    save(filename,'file');
                end
                
                app.count           = app.count + 1;
                
                app.videopath   = app.framelist{app.count,1};
                app.framenb     = app.framelist{app.count,2};
                app.frame       = app.framelist{app.count,3};
                
                imshow(app.frame,'Parent',app.UIAxes)
                
                tic
                
            else
                uialert(app.ImageLabelerUIFigure,'All images are labeled','Done','Icon','success');
            end
            app.ProgressGauge.Value = ( app.count /length(app.framelist))*100 ;
        end

        % Button pushed function: SelectTextFileButton
        function ImportTextFile(app, event)
            
            if isempty(app.folder)
                uialert(app.ImageLabelerUIFigure,'First select the folder','Error','Icon','warning');
            elseif isempty(app.textfile)
                [name,path]             = uigetfile('*.txt');
                app.ImageLabelerUIFigure.Visible = 'off';
                app.ImageLabelerUIFigure.Visible = 'on';
                app.textLamp.Color      = 'green';
                fullpath                = fullfile(path,name);
                app.textfile            = fopen(fullpath);
                
                if isempty(app.framelist)
                    app.framelist           = findFrames(app,app.textfile,app.folder);
                    app.framelist
                end
                
                app.framelist           = loadFrames(app,app.framelist,app.folder,app.count,app.count+app.maxframes);
                
                app.videopath           = app.framelist{app.count,1};
                app.framenb             = app.framelist{app.count,2};
                app.frame               = app.framelist{app.count,3};
               
                
                imshow(app.frame,'Parent',app.UIAxes)
                
                tic
            else 
                uialert(app.ImageLabelerUIFigure,'Text file was already selected','Error','Icon','warning');
            end
        end

        % Button pushed function: SelectFolderButton
        function SelectFolder(app, event)
            if isempty(app.folder)
                
                app.folder                          = uigetdir;
                app.ImageLabelerUIFigure.Visible    = 'off';
                app.ImageLabelerUIFigure.Visible    = 'on';
                app.folderLamp.Color                = 'green';
  
                
                ind                     = strfind(app.folder, '/');
                foldername              = app.folder(ind(end)+1:end);
                filename                = strcat(app.directory,'/', foldername,'.mat');
                
                if exist(filename)
                    file                    = load(filename);
                    app.framelist           = struct2cell(file);
                    app.framelist           = table2cell(app.framelist{1});
                    app.count               = sum(~cellfun(@isempty,app.framelist(:,4)));
                    app.UITable.Data        = [app.framelist(1:app.count,1) app.framelist(1:app.count,2) app.framelist(1:app.count,4) app.framelist(1:app.count,5)];    
                    app.ProgressGauge.Value = ( app.count / length(app.framelist))*100 ;
                end
                
            else
                uialert(app.ImageLabelerUIFigure,'Folder was already selected','Error','Icon','warning');
            end
        end

        % Button pushed function: BackButton
        function backPush(app, event)
            app.count           = app.count - 1 ;
            app.UITable.Data    = app.UITable.Data(1:app.count,:);
            
            app.videopath           = app.framelist{app.count,1};
            app.framenb             = app.framelist{app.count,2};
            app.frame               = app.framelist{app.count,3};
               
            imshow(app.frame,'Parent',app.UIAxes)
            
            app.ProgressGauge.Value = (app.count / length(app.framelist))*100 ;


        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ImageLabelerUIFigure = uifigure;
            app.ImageLabelerUIFigure.Name = 'Image Labeler';
            app.TabGroup = uitabgroup(app.ImageLabelerUIFigure);
            app.LabelTab = uitab(app.TabGroup);
            app.LabelTab.Title = 'Label';
            app.TrueButton = uibutton(app.LabelTab, 'push');
            app.TrueButton.ButtonPushedFcn = createCallbackFcn(app, @TruePush, true);
            app.TrueButton.Text = 'True';
            app.FalseButton = uibutton(app.LabelTab, 'push');
            app.FalseButton.ButtonPushedFcn = createCallbackFcn(app, @FalsePush, true);
            app.FalseButton.Text = 'False';
            app.ExportasCSVButton = uibutton(app.LabelTab, 'push');
            app.ExportasCSVButton.ButtonPushedFcn = createCallbackFcn(app, @ExportPush, true);
            app.ExportasCSVButton.Text = 'Export as CSV';
            app.ProgressGaugeLabel = uilabel(app.LabelTab);
            app.ProgressGaugeLabel.Text = 'Progress';
            app.ProgressGauge = uigauge(app.LabelTab, 'semicircular');
            app.UnusableButton = uibutton(app.LabelTab, 'push');
            app.UnusableButton.ButtonPushedFcn = createCallbackFcn(app, @UnusablePush, true);
            app.UnusableButton.Text = 'Unusable';
            app.SelectTextFileButton = uibutton(app.LabelTab, 'push');
            app.SelectTextFileButton.ButtonPushedFcn = createCallbackFcn(app, @ImportTextFile, true);
            app.SelectTextFileButton.Text = 'Select Text File';
            app.Label = uilabel(app.LabelTab);
            app.Label.Text = '2.';
            app.Label_3 = uilabel(app.LabelTab);
            app.Label_3.Text = '3.';
            app.SelectFolderButton = uibutton(app.LabelTab, 'push');
            app.SelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolder, true);
            app.SelectFolderButton.Text = 'Select Folder';
            app.Label_4 = uilabel(app.LabelTab);
            app.Label_4.Text = '1.';
            app.Label_5 = uilabel(app.LabelTab);
            app.Label_5.Text = '';
            app.folderLamp = uilamp(app.LabelTab);
            app.Lamp_2Label = uilabel(app.LabelTab);
            app.Lamp_2Label.Text = '';
            app.textLamp = uilamp(app.LabelTab);
            app.Lamp_3Label = uilabel(app.LabelTab);
            app.Lamp_3Label.Text = '';
            app.exportLamp = uilamp(app.LabelTab);
            app.BackButton = uibutton(app.LabelTab, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @backPush, true);
            app.BackButton.Text = 'Back';
            app.UIAxes = uiaxes(app.LabelTab);
            title(app.UIAxes, 'Label the image')
            app.DataTab = uitab(app.TabGroup);
            app.DataTab.Title = 'Data';
            app.UITable = uitable(app.DataTab);
            app.UITable.ColumnName = {'Video'; 'Frame'; 'Label'; 'Time'};
            app.UITable.RowName = {};
        end
    end

    methods (Access = public)

        % Construct app
        function app = imageLabeler

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ImageLabelerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ImageLabelerUIFigure)
        end
    end
end